classdef Logger < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (GetAccess = ?matlab.unittest.TestCase, SetAccess = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        Label       matlab.ui.control.Label
    end

    methods
        function updateText(comp, event)
            if event.Data.exitflag == 1 % Success
                switch event.EventName
                    case 'LoadingEnded'
                        str = "Data loaded from: ";
                    case 'ReportEnded'
                        str = "Report saved at: ";
                    case 'SavingEnded'
                        str = "Results saved at: ";
                end
                comp.Label.Text = str + event.Data.path;
            end
        end % updateText
    end % methods

    methods (Access = public)
        function obj = Logger(parent)
            obj@matlab.ui.componentcontainer.ComponentContainer(Parent=parent);
        end % constructor
    end % methods (Access = public)

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 320 240];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.Label = uilabel(comp.GridLayout);
            comp.Label.Text = ' Data not loaded';
        end
    end
end