classdef cDataModel < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (GetAccess = ?matlab.unittest.TestCase, SetAccess = private, Transient, NonCopyable)
        GridLayout         matlab.ui.container.GridLayout
        SaveResultsButton  matlab.ui.control.Button
        FitDataButton      matlab.ui.control.Button
        LoadDataButton     matlab.ui.control.Button
    end

    properties (Access = private)
        Listener (:,1) event.listener
        Model (1,1) Model
    end
    
    methods (Access = private)
        function onAnalysisEnded(comp)
            comp.SaveResultsButton.Enable = true;
        end % onAnalysisEnded

        function onLoadingEnded(comp, event)
            if event.Data.exitflag == 1 % Data was loaded successfully
                comp.FitDataButton.Enable = true;
            end
        end % onLoadingEnded
    end % methods (Access = private)

    methods
        function comp = cDataModel(model, parent)
            comp@matlab.ui.componentcontainer.ComponentContainer(Parent=parent)

            comp.Listener = listener(model, "LoadingEnded", @(~,e) comp.onLoadingEnded(e));
            comp.Listener(end+1) = listener(model, "AnalysisEnded", @(~,~) comp.onAnalysisEnded);

            comp.Model = model;
        end % constructor
    end % methods

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(comp, event)
            loadData(comp.Model);
        end

        % Button pushed function: FitDataButton
        function FitDataButtonPushed(comp, event)
            analyzeData(comp.Model);
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(comp, event)
            saveResults(comp.Model);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 493 315];
            comp.BackgroundColor = [0.9607843137254902 0.9607843137254902 0.9607843137254902];
            comp.GridLayout = uigridlayout(comp);
            comp.LoadDataButton = uibutton(comp.GridLayout, 'push');
            comp.LoadDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @LoadDataButtonPushed, true);
            comp.LoadDataButton.Tooltip = {'Load test data from Excel file'};
            comp.LoadDataButton.Text = 'Load Data...';
            comp.FitDataButton = uibutton(comp.GridLayout, 'push');
            comp.FitDataButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @FitDataButtonPushed, true);
            comp.FitDataButton.Enable = 'off';
            comp.FitDataButton.Tooltip = {'Fit linear model on loaded data'};
            comp.FitDataButton.Text = 'Fit Data';
            comp.SaveResultsButton = uibutton(comp.GridLayout, 'push');
            comp.SaveResultsButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveResultsButtonPushed, true);
            comp.SaveResultsButton.Enable = 'off';
            comp.SaveResultsButton.Tooltip = {'Save results in MAT file'};
            comp.SaveResultsButton.Text = 'Save Results...';
        end
    end
end