classdef launcher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        GenerateReportMenu  matlab.ui.container.Menu
        GridLayout          matlab.ui.container.GridLayout
        GridLayout2         matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
    end

    properties (Access = private)
        Dlg matlab.ui.dialog.ProgressDialog
        Listener (:,1) event.listener
    end % properties (Access = private)

    methods (Access = private)
        function onAnalysisEnded(app)
            close(app.Dlg)
            app.GenerateReportMenu.Enable = true;
        end % onAnalysisEnded

        function onAnalysisStarted(app)
            app.Dlg = uiprogressdlg(app.UIFigure, ...
                Title = "Please Wait", ...
                Message = "Analyzing Data...", ...
                Indeterminate = "on");
        end % onAnalysisStarted

        function onLoadingEnded(app, event)
            if ~event.Data.exitflag % Error
                uialert(app.UIFigure, ...
                    "Error loading data.", "Error", Icon="error")
            end
            figure(app.UIFigure);
            close(app.Dlg)
        end % onLoadingEnded

        function onLoadingStarted(app)
            figure(app.UIFigure) % bring app to focus
            app.Dlg = uiprogressdlg(app.UIFigure, ...
                Title = "Please Wait", ...
                Message = "Loading Data...", ...
                Indeterminate = "on");
        end % onLoadingStarted

        function onReportStarted(app)
            app.Dlg = uiprogressdlg(app.UIFigure, ...
                Title = "Please Wait", ...
                Message = "Generating Report...");
        end % onReportStarted

        function onReportProgress(app, event)
            app.Dlg.Value = event.Data;
            app.Dlg.Message = "Generating Report... (" + event.Data*100 + "%)";
            drawnow
        end % onReportProgress

        function onReportEnded(app, event)
            if ~event.Data.exitflag % Error
                uialert(app.UIFigure, ...
                    event.Data.ME, "Error", Icon="error")
            else
                uialert(app.UIFigure,...
                    "Report generated successfully.", ...
                    "Success", Icon="success", Interpreter="html");
            end
            close(app.Dlg)
        end % onReportEnded

        function onSavingEnded(app, event)
            figure(app.UIFigure) % bring app to focus
            switch event.Data.exitflag
                case 0
                    % User did not select, do nothing
                case 1 % Success
                    uialert(app.UIFigure, ...
                        "Results exported successfully.", ...
                        "Success", Icon="success")
                case 2 % Error
                    uialert(app.UIFigure, ...
                        "Cannot export results due to an error.", ...
                        "Error", Icon="error")
            end
        end % onSavingEnded
    end % methods (Access = private)

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chooser)
            arguments
                app
                chooser Chooser = DefaultChooser % for mock testing
            end

            % Move app at the center of the scren
            p = groot().ScreenSize(3:4); sz = app.UIFigure.Position(3:4);
            app.UIFigure.Position = [(p-sz)/2 sz];

            m = Model(chooser);
            app.Listener(end+1) = listener(m, "AnalysisEnded", @(~,~) app.onAnalysisEnded);
            app.Listener(end+1) = listener(m, "AnalysisStarted", @(~,~) app.onAnalysisStarted);
            app.Listener(end+1) = listener(m, "LoadingEnded", @(~,e) app.onLoadingEnded(e));
            app.Listener(end+1) = listener(m, "LoadingStarted", @(~,~) app.onLoadingStarted);
            app.Listener(end+1) = listener(m, "SavingEnded", @(~,e) app.onSavingEnded(e));
            
            % Create views and controllers
            cDataModel(m, app.GridLayout2);
            vda = vDataAnalysis(m, app.GridLayout3);
            l = Logger(app.GridLayout);
            addlistener(m, "LoadingEnded", @(~,e) l.updateText(e));
            addlistener(m, "SavingEnded", @(~,e) l.updateText(e));

            % Create report object
            r = AnalysisReport;
            app.Listener(end+1) = listener(r, "ReportStarted", @(~,~) app.onReportStarted);
            app.Listener(end+1) = listener(r, "ReportProgress", @(~,e) app.onReportProgress(e));
            app.Listener(end+1) = listener(r, "ReportEnded", @(~,e) app.onReportEnded(e));
            app.Listener(end+1) = listener(r, "NoReportLicense", @(~,~) app.onReportEnded(e));
            addlistener(vda, "PlotUpdated", @(~,e) r.saveOutput(e));
            addlistener(m, "LoadingEnded", @(~,e) r.saveOutput(e));
            addlistener(m, "AnalysisEnded", @(~,e) r.saveOutput(e));
            addlistener(r, "ReportEnded", @(~,e) l.updateText(e));
            app.GenerateReportMenu.MenuSelectedFcn = @(~,~) generatereport;
            
            function generatereport
                try
                    run(r, chooser.getReportFile);
                catch ME
                    uialert(app.UIFigure, ME.message, "Error", Icon="error")
                end
            end % generatereport
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Curve Fitter App';
            app.UIFigure.Icon = 'app-icon.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.GenerateReportMenu = uimenu(app.FileMenu);
            app.GenerateReportMenu.Enable = 'off';
            app.GenerateReportMenu.Text = 'Generate Report';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = launcher(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end