classdef vDataAnalysis < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (GetAccess = ?matlab.unittest.TestCase, SetAccess = private, Transient, NonCopyable)
        GridLayout   matlab.ui.container.GridLayout
        DataTable    matlab.ui.control.Table
        GridLayout2  matlab.ui.container.GridLayout
        FitTable     matlab.ui.control.Table
        UIAxes       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Listener (:,1) event.listener
        Model (1,1) Model
    end
    
    events (NotifyAccess = private)
        PlotUpdated
    end % events (NotifyAccess = private)

    properties (GetAccess = ?matlab.unittest.TestCase, SetAccess = private)
        Scatter matlab.graphics.chart.primitive.Scatter % scatter plot for input data
        Line matlab.graphics.primitive.Line % Line showing best fit to input data
    end % properties (GetAccess = ?matlab.unittest.TestCase, SetAccess = private)

    methods (Access = private)
        function onAnalysisEnded(comp)
            comp.FitTable.Data = comp.Model.BestFitCoefs;
            set(comp.Line, XData=comp.Model.Data.X, YData=comp.Model.BestFitVals)
            % For report generation
            out.data = comp.UIAxes;
            notify(comp, "PlotUpdated", NotifyData(out))
        end

        function onLoadingEnded(comp, s, e)
            if e.Data.exitflag == 1 % Data was loaded successfully
                comp.DataTable.Data = s.Data;
                comp.DataTable.ColumnName = s.Data.Properties.VariableNames;
                set(comp.Scatter, XData=s.Data.X, YData = s.Data.Y)
            end
        end % onLoadingEnded
    end % methods (Access = private)

    methods (Access = public)
        function comp = vDataAnalysis(model, parent)
            comp@matlab.ui.componentcontainer.ComponentContainer(Parent=parent)
            comp.Listener = listener(model, "LoadingEnded", @comp.onLoadingEnded);
            comp.Listener(end+1) = listener(model, "AnalysisEnded", @(~,~) comp.onAnalysisEnded);

            % Initialize graphics
            disableDefaultInteractivity(comp.UIAxes)
            comp.Scatter = scatter(comp.UIAxes, nan, nan, 'filled', DisplayName="Input Data");
            comp.Line = line(comp.UIAxes, nan, nan, LineWidth=1.5, DisplayName="Best Fit");

            % Initialize table
            comp.FitTable.Data = [nan nan];

            % Store model
            comp.Model = model;
        end % constructor
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components

        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 525 346];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.UIAxes = uiaxes(comp.GridLayout2);
            xlabel(comp.UIAxes, 'X')
            ylabel(comp.UIAxes, 'Y')
            zlabel(comp.UIAxes, 'Z')
            comp.FitTable = uitable(comp.GridLayout2);
            comp.FitTable.ColumnName = {'Slope'; 'Intercept'};
            comp.FitTable.RowName = {};
            comp.DataTable = uitable(comp.GridLayout);
            comp.DataTable.ColumnName = {'X'; 'Y'};
            comp.DataTable.RowName = {};
        end
    end
end